classdef TrussGAVizapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ParetoFrontTab                 matlab.ui.container.Tab
        ParetoAxes                     matlab.ui.control.UIAxes
        SelectedDesignAxes             matlab.ui.control.UIAxes
        TrussCMat                      matlab.ui.control.Table
        FibreCTab                      matlab.ui.control.Table
        FeasibilityScoreTextAreaLabel  matlab.ui.control.Label
        FeasibilityScoreTextArea       matlab.ui.control.TextArea
        StabilityScoreTextAreaLabel    matlab.ui.control.Label
        StabilityScoreTextArea         matlab.ui.control.TextArea
        VolumeFractionTextAreaLabel    matlab.ui.control.Label
        VolumeFractionTextArea         matlab.ui.control.TextArea
        TrueObjectives                 matlab.ui.control.Table
        FibreStiffnessModelLabel       matlab.ui.control.Label
        TrueObjectivesLabel            matlab.ui.control.Label
        TrussModelLabel                matlab.ui.control.Label
        FeatureAnalysisTab             matlab.ui.container.Tab
        FeatureBoxAxes                 matlab.ui.control.UIAxes
        FeatureDropDownLabel           matlab.ui.control.Label
        FeatureDropDown                matlab.ui.control.DropDown
        HypervolumeTab                 matlab.ui.container.Tab
        HypervolumeAxes                matlab.ui.control.UIAxes
        IndividualHypervolumeAxes      matlab.ui.control.UIAxes
        NFEforHVjumpTextAreaLabel      matlab.ui.control.Label
        NFEforHVjumpTextArea           matlab.ui.control.TextArea
        NFEBoxAxes                     matlab.ui.control.UIAxes
        AlgorithmDropDownLabel         matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
        ModelDropDownLabel             matlab.ui.control.Label
        ModelDropDown                  matlab.ui.control.DropDown
        VISUALIZEButton                matlab.ui.control.Button
        RunNumberEditFieldLabel        matlab.ui.control.Label
        RunNumberEditField             matlab.ui.control.EditField
        InitializationDropDownLabel    matlab.ui.control.Label
        InitializationDropDown         matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        pen_fac; % Penalty Factor for computation of true objectives
        run_number;
        %pareto_objectives_complete;
        feas_complete;
        stab_complete;
        designs_complete;
        nfe_complete;
        feas_pareto_final;
        stab_pareto_final;
        designs_pareto_final;
        filename;
        filename2;
        filename3;
        filename3_fullpop;
        filepath2;
        filepath3;
        filepath4;
        feature;
        NodalCoords;
        FullConnArray;
        MemberRadius;
        SideElementLength;
        SideNodeNumber;
        init_choice;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VISUALIZEButton
        function VISUALIZEButtonPushed(app, event)
            filepath = 'C:\\SEAK Lab\\SEAK Lab Github\\KD3M3\\Truss_AOS\\result\\';
        
            full_filename = strcat(app.filename,num2str(app.run_number),app.filename2,app.filename3);
            full_filepath = strcat(filepath,app.filepath2,app.filepath3,app.filepath4,full_filename);
            data_table = readtable(full_filepath,'Format','%s%f%f%f%f','HeaderLines',1);
            pop_size =  size(data_table,1);
            csv_data = zeros(pop_size,4);
            designs = strings(pop_size);
            csv_data = data_table(:,2:end);
            designs = data_table(:,1);
        
            % Parameters
            E = 10000; % Young's Modulus for polymeric material (example: 10000 Pa)
            app.SideElementLength = 0.05; % Unit square side length (NOT individual truss length) (example: 5 cm)
            app.MemberRadius = 50*(10^-6); % Radius for cross-sectional area of (assumed circular) truss members (example: 50 micrometers)
            %A = pi*(r^2); % Cross-sectional area of truss member
            app.NodalCoords = app.SideElementLength.*[0,0;0,0.5;0,1;0.5,0;0.5,0.5;0.5,1;1,0;1,0.5;1,1]; 
            app.FullConnArray = [1,2; 1,3; 1,4; 1,5; 1,6; 1,7; 1,8; 1,9; 2,3; 2,4; 2,5; 2,6; 2,7; 2,8; 2,9; 3,4; 3,5; 3,6; 3,7; 3,8; 3,9; 4,5; 4,6; 4,7; 4,8; 4,9; 5,6; 5,7; 5,8; 5,9; 6,7; 6,8; 6,9; 7,8; 7,9; 8,9];
            %c_ratio = 1;
            app.SideNodeNumber = 3;
            nucFac = 1;
        
            designs_array = designs{:,:};
            f_penalized = csv_data{:,1:2};
            feas_scores = csv_data{:,3}; 
            stab_scores = csv_data{:,4};
        
            pareto_bool = paretofront(f_penalized);
            f_pareto = f_penalized(pareto_bool==1,:);
            designs_pareto = designs_array(pareto_bool==1);
            feas_pareto = feas_scores(pareto_bool==1);
            stab_pareto = stab_scores(pareto_bool==1); 
            f_pareto_true = [f_pareto(:,1), -f_pareto(:,2)]; % objective 1 is to be
            % minimized while objective 2 is to be maximized  
            plot(f_pareto_true(:,1),f_pareto_true(:,2),'*','ButtonDownFcn',{@plot_pareto_callback_app,app.NodalCoords,app.FullConnArray,designs_pareto,feas_pareto,stab_pareto,f_pareto,app.SideElementLength,app.MemberRadius,E,app.pen_fac,app.SideNodeNumber,nucFac,app},'Parent',app.ParetoAxes)
            set(get(app.ParetoAxes, 'xlabel'), 'string', '$\frac{\left|\frac{C_{22}}{C_{11}} - c_{target}\right|}{15} - k_{pen}\frac{log(g_{feas}) + log(g_{stab})}{2}$','Interpreter','latex') 
            set(get(app.ParetoAxes, 'ylabel'), 'string', '$\frac{\frac{C_{22}}{v_f}}{8500} + k_{pen}\frac{log(g_{feas}) + log(g_{stab})}{2}$','Interpreter','latex') 
        
            app.FeasibilityScoreTextArea.Value = '';
            app.StabilityScoreTextArea.Value = '';
            app.TrussCMat.Data = cell(3,3);
            app.TrueObjectives.Data = cell(1,2);
            app.FibreCTab.Data = cell(2,1);
            app.VolumeFractionTextArea.Value = ''; 
            cla(app.SelectedDesignAxes);
            cla(app.FeatureBoxAxes);
            
            app.feas_pareto_final = feas_pareto;
            app.stab_pareto_final = stab_pareto;
            app.designs_pareto_final = designs_pareto;
            %app.pareto_objectives = f_pareto;
            
            %%% Compute and plot hypervolume
            app.IndividualHypervolumeAxes.Visible = 'off';
            app.NFEBoxAxes.Visible = 'off';
            
            % Read full population from csv file
            filepath = 'C:\\SEAK Lab\\SEAK Lab Github\\KD3M3\\Truss_AOS\\result\\';
            full_filename_fullpop = strcat(app.filename,num2str(app.run_number),app.filename2,app.filename3_fullpop);
            full_filepath_fullpop = strcat(filepath,app.filepath2,app.filepath3,app.filepath4,full_filename_fullpop);
            data_table_fullpop = readtable(full_filepath_fullpop,'Format','%s%f%f%f%f%f','HeaderLines',1);%,'ReadVariableNames',true);
            
            csv_data_fullpop = data_table_fullpop(:,2:end);
            designs_fullpop = data_table_fullpop(:,1);
            
            designs_array_fullpop = designs_fullpop{:,:};
            feas_scores_fullpop = csv_data_fullpop{:,4}; 
            stab_scores_fullpop = csv_data_fullpop{:,5};
            nfe_fullpop = csv_data_fullpop{:,1};
            f_fullpop = csv_data_fullpop{:,2:3};
            
            app.feas_complete = feas_scores_fullpop;
            app.stab_complete = stab_scores_fullpop;
            app.designs_complete = designs_array_fullpop;
            app.nfe_complete = nfe_fullpop;
            
            f1_max = max(f_fullpop(:,1));
            f2_max = max(f_fullpop(:,2));
            f1_min = min(f_fullpop(:,1));
            f2_min = min(f_fullpop(:,2));
            
            [nfe_sorted, sort_indices] = sort(nfe_fullpop);
            f_sorted = f_fullpop(sort_indices,:);
            %feas_sorted = feas_scores_fullpop(sort_indices);
            %stab_sorted = stab_scores_fullpop(sort_indices);
            
            nfe_array = linspace(0,10000,200);
            hypervolume_array = zeros(200,1);
            
            ref_point = [1.1,1.1];
            
            for i = 1:length(nfe_array)
                closest_index = find_closest_index(nfe_sorted,nfe_array(i));
                f_current = [f_sorted(1:closest_index,1),f_sorted(1:closest_index,2)]; 
                pareto_bool = paretofront(f_current);
                f_current_pareto = f_current(pareto_bool==1,:);
                f_current_pareto_normalized = [((f_current_pareto(:,1) - f1_min)/(f1_max - f1_min)),((f_current_pareto(:,2) - f2_min)/(f2_max - f2_min))];
                hypervolume_array(i) = lebesgue_measure(f_current_pareto_normalized',ref_point)/(1.1^2);
            end
            %disp(hypervolume_array)
            
            plot(nfe_array,hypervolume_array,'Parent',app.HypervolumeAxes);
            set(get(app.HypervolumeAxes, 'xlabel'),'string','Number of Function Evaluations')
            set(get(app.HypervolumeAxes, 'ylabel'),'string','Hypervolume')
            
            if (strcmp(app.init_choice,'Biased Initialization'))
                app.NFEforHVjumpTextArea.Visible = 'off';
                app.NFEforHVjumpTextAreaLabel.Visible = 'off';
            else
                app.NFEforHVjumpTextArea.Visible = 'on';
                app.NFEforHVjumpTextAreaLabel.Visible = 'on';
                highest_hv_jump = 0;
                jump_index = 0;
                for i = 1:(length(hypervolume_array)-1)
                    hv_jump = hypervolume_array(i+1) - hypervolume_array(i);
                    if (hv_jump > highest_hv_jump)
                        highest_hv_jump = hv_jump;
                        jump_index = i;
                    end
                end
                app.NFEforHVjumpTextArea.Value = num2str(round(nfe_array(jump_index)));
            end
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            algorithm_choice = app.AlgorithmDropDown.Value;
            % Set filepath2 based on the selected value
            switch algorithm_choice
                case 'Epsilon MOEA'
                    app.filepath2 = 'Epsilon MOEA Runs\\';
                    app.filename = 'EpsilonMOEA_emoea';
                case 'AOS - Constraint Independent'
                    app.filepath2 = 'AOS MOEA Runs\\Feas and Stab False\\';
                    app.filename = 'AOSMOEA_constraint_adaptive';
                    app.filename2 = '';
                    app.filepath4 = '';
                case 'AOS - Feasibility Dependent'
                    app.filepath2 = 'AOS MOEA Runs\\Feas True\\';
                    app.filename = 'AOSMOEA_constraint_adaptive';
                    app.filename2 = '';
                    app.filepath4 = '';
                case 'AOS - Constraint Dependent'
                    app.filepath2 = 'AOS MOEA Runs\\Feas and Stab True\\';
                    app.filename = 'AOSMOEA_constraint_adaptive';
                    app.filename2 = '';
                    app.filepath4 = '';
                case 'Disjunctive Normal Form'
                    app.filepath2 = 'Soft Constraint Runs\\Disjunctive Normal Form Runs\\';
                    app.filepath4 = '';
                    app.filename = 'EpsilonMOEA_emoea_dnf';
                    app.filename2 = '';
                case 'Adaptive Constraint Handling'
                    app.filepath2 = 'Soft Constraint Runs\\Adaptive Constraint Handling Runs\\';
                    app.filepath4 = '';
                    app.filename = 'EpsilonMOEA_emoea_ach';
                    app.filename2 = '';
            end
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            modelChoice = app.ModelDropDown.Value;
            % Set filepath3 based on the selected value
            switch modelChoice
                case 'Fibre Stiffness'
                    app.filepath3 = 'Fibre Stiffness code run results\\';
                    if (strcmp(app.filename,'EpsilonMOEA_emoea_dnf') || strcmp(app.filename,'EpsilonMOEA_emoea_ach')) 
                        app.filename3 = '_fibre_biased_feasstab_penalized.csv';
                        app.filename3_fullpop = '_fibre_biased_feasstab_penalizedfullpop.csv';
                    else
                        app.filename3 = '_fibrestiffness.csv';
                        app.filename3_fullpop = '_fibrestiffness_fullpop.csv';
                    end
                    app.pen_fac = 1.5;
                case 'Truss Stiffness'
                    app.filepath3 = 'Truss code run results\\';
                    if (strcmp(app.filename,'EpsilonMOEA_emoea_dnf') || strcmp(app.filename,'EpsilonMOEA_emoea_ach')) 
                        app.filename3 = '_truss_biased_feasstab_penalized.csv';
                        app.filename3_fullpop = '_truss_biased_feasstab_penalizedfullpop.csv';
                    else
                        app.filename3 = '_fibrestiffness.csv';
                        app.filename3_fullpop = '_fibrestiffness_fullpop.csv';
                    end
                    app.pen_fac = 1;
            end
        end

        % Value changed function: RunNumberEditField
        function RunNumberEditFieldValueChanged(app, event)
            app.run_number = app.RunNumberEditField.Value;
        end

        % Value changed function: FeatureDropDown
        function FeatureDropDownValueChanged(app, event)
            app.feature = app.FeatureDropDown.Value;
            
            % Read initial population
            nfe_init_indices = app.nfe_complete == 0;
            %feas_scores_init = app.feas_complete(nfe_init_indices);
            %stab_scores_init = app.stab_complete(nfe_init_indices);
            designs_init = app.designs_complete(nfe_init_indices);
            
            if (strcmp(app.feature,'Select Feature'))
                cla(app.FeatureBoxAxes);
                set(get(app.FeatureBoxAxes, 'xlabel'), 'string', '')
                set(get(app.FeatureBoxAxes, 'ylabel'), 'string', '')
                set(get(app.FeatureBoxAxes, 'title'), 'string', '')
            elseif (strcmp(app.feature,'Volume Fraction'))
                % Compute volume fraction for initial population
                vf_init = zeros(size(designs_init,1),1);
                for i = 1:size(designs_init,1)
                    design_current = designs_init{i};
                    design_bool = zeros(size(design_current,2),1);
                    for j = 1:size(design_current,2)
                        design_bool(j) = str2num(design_current(j));
                    end
                    CA_des = app.FullConnArray(design_bool~=0,:);
                    vf_init(i,1) = calcVF(app.NodalCoords,CA_des,app.MemberRadius,app.SideElementLength);
                end
                % Compute volume fraction for final pareto front
                vf_final_pareto = zeros(size(app.designs_pareto_final,1),1);
                for i = 1:size(app.designs_pareto_final,1)
                    design_current = app.designs_pareto_final{i};
                    design_bool = zeros(size(design_current,2),1);
                    for j = 1:size(design_current,2)
                        design_bool(j) = str2num(design_current(j));
                    end
                    CA_des = app.FullConnArray(design_bool~=0,:);
                    vf_final_pareto(i,1) = calcVF(app.NodalCoords,CA_des,app.MemberRadius,app.SideElementLength);
                end
                vf_bp = [vf_init;vf_final_pareto];
                vf_groups = [zeros(size(vf_init,1),1);ones(size(vf_final_pareto,1),1)];
                boxplot(app.FeatureBoxAxes,vf_bp,vf_groups);
                app.FeatureBoxAxes.XTickLabel = {'Initial Population','Final Pareto Front'};                
                set(get(app.FeatureBoxAxes, 'xlabel'), 'string', '')
                set(get(app.FeatureBoxAxes, 'ylabel'), 'string', 'Volume Fraction')
                set(get(app.FeatureBoxAxes, 'title'), 'string', 'Volume Fraction Comparison Boxplot')

            elseif (strcmp(app.feature,'Feasibility Score'))
                % Compute Feasibility Scores for initial population
                feas_init = zeros(size(designs_init,1),1);
                for i = 1:size(designs_init,1)
                    design_current = designs_init{i};
                    design_bool = zeros(size(design_current,2),1);
                    for j = 1:size(design_current,2)
                        design_bool(j) = str2num(design_current(j));
                    end
                    CA_des = app.FullConnArray(design_bool~=0,:);
                    feas_init(i,1) = feasibility_checker_nonbinary_V2(app.NodalCoords,CA_des);
                end
                feas_bp = [feas_init;app.feas_pareto_final];
                feas_groups = [zeros(size(feas_init,1),1);ones(size(app.feas_pareto_final,1),1)];
                boxplot(app.FeatureBoxAxes,feas_bp,feas_groups);
                app.FeatureBoxAxes.XTickLabel = {'Initial Population','Final Pareto Front'};
                set(get(app.FeatureBoxAxes, 'xlabel'), 'string', '')
                set(get(app.FeatureBoxAxes, 'ylabel'), 'string', 'Feasibility Score')
                set(get(app.FeatureBoxAxes, 'title'), 'string', 'Feasibility Score Comparison Boxplot')
                
            elseif (strcmp(app.feature,'Stability Score'))
                % Compute Stability Scores for initial population
                stab_init = zeros(size(designs_init,1),1);
                for i = 1:size(designs_init,1)
                    design_current = designs_init{i};
                    design_bool = zeros(size(design_current,2),1);
                    for j = 1:size(design_current,2)
                        design_bool(j) = str2num(design_current(j));
                    end
                    CA_des = app.FullConnArray(design_bool~=0,:);
                    stab_init(i,1) = stabilityTester_2D_V7(app.SideNodeNumber,CA_des,app.NodalCoords,app.SideElementLength);
                end
                stab_bp = [stab_init;app.stab_pareto_final];
                stab_groups = [zeros(size(stab_init,1),1);ones(size(app.stab_pareto_final,1),1)];
                boxplot(app.FeatureBoxAxes,stab_bp,stab_groups);
                app.FeatureBoxAxes.XTickLabel = {'Initial Population','Final Pareto Front'};                
                set(get(app.FeatureBoxAxes, 'xlabel'), 'string', '')
                set(get(app.FeatureBoxAxes, 'ylabel'), 'string', 'Stability Score')
                set(get(app.FeatureBoxAxes, 'title'), 'string', 'Stability Score Comparison Boxplot')
                
            end
                
        end

        % Value changed function: InitializationDropDown
        function InitializationDropDownValueChanged(app, event)
            app.init_choice = app.InitializationDropDown.Value;
            % Set filename3 based on the selected value
            switch app.init_choice
                case 'Biased Initialization'
                    app.filename2 = '_biasedinit';
                    app.filepath4 = 'Biased Initialization\\';
                case 'Random Initialization'
                    app.filename2 = '';
                    app.filepath4 = 'Random Initialization\\';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParetoFrontTab = uitab(app.TabGroup);
            app.ParetoFrontTab.Title = 'Pareto Front';
            app.ParetoAxes = uiaxes(app.ParetoFrontTab);
            title(app.ParetoAxes, 'Pareto Front')
            xlabel(app.ParetoAxes, 'X')
            ylabel(app.ParetoAxes, 'Y')
            app.SelectedDesignAxes = uiaxes(app.ParetoFrontTab);
            title(app.SelectedDesignAxes, 'Selected  Design')
            xlabel(app.SelectedDesignAxes, 'X')
            ylabel(app.SelectedDesignAxes, 'Y')
            app.TrussCMat = uitable(app.ParetoFrontTab);
            app.TrussCMat.ColumnName = {'C_i1'; 'C_i2'; 'C_i3'; 'Column 4'};
            app.TrussCMat.RowName = {};
            app.FibreCTab = uitable(app.ParetoFrontTab);
            app.FibreCTab.ColumnName = {'C_11'; 'C_22'};
            app.FibreCTab.RowName = {};
            app.FeasibilityScoreTextAreaLabel = uilabel(app.ParetoFrontTab);
            app.FeasibilityScoreTextAreaLabel.Text = 'Feasibility Score';
            app.FeasibilityScoreTextArea = uitextarea(app.ParetoFrontTab);
            app.StabilityScoreTextAreaLabel = uilabel(app.ParetoFrontTab);
            app.StabilityScoreTextAreaLabel.Text = 'Stability Score';
            app.StabilityScoreTextArea = uitextarea(app.ParetoFrontTab);
            app.VolumeFractionTextAreaLabel = uilabel(app.ParetoFrontTab);
            app.VolumeFractionTextAreaLabel.Text = 'Volume Fraction';
            app.VolumeFractionTextArea = uitextarea(app.ParetoFrontTab);
            app.TrueObjectives = uitable(app.ParetoFrontTab);
            app.TrueObjectives.ColumnName = {'(C_11/C_22) - 1'; '(C_22/v_f)'};
            app.TrueObjectives.RowName = {};
            app.FibreStiffnessModelLabel = uilabel(app.ParetoFrontTab);
            app.FibreStiffnessModelLabel.Text = 'Fibre Stiffness Model';
            app.TrueObjectivesLabel = uilabel(app.ParetoFrontTab);
            app.TrueObjectivesLabel.Text = 'True Objectives';
            app.TrussModelLabel = uilabel(app.ParetoFrontTab);
            app.TrussModelLabel.Text = 'Truss Model';
            app.FeatureAnalysisTab = uitab(app.TabGroup);
            app.FeatureAnalysisTab.Title = 'Feature Analysis';
            app.FeatureBoxAxes = uiaxes(app.FeatureAnalysisTab);
            title(app.FeatureBoxAxes, '')
            xlabel(app.FeatureBoxAxes, 'X')
            ylabel(app.FeatureBoxAxes, 'Y')
            app.FeatureDropDownLabel = uilabel(app.FeatureAnalysisTab);
            app.FeatureDropDownLabel.Text = 'Feature';
            app.FeatureDropDown = uidropdown(app.FeatureAnalysisTab);
            app.FeatureDropDown.Items = {'Select Feature', 'Volume Fraction', 'Feasibility Score', 'Stability Score'};
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureDropDownValueChanged, true);
            app.FeatureDropDown.Value = 'Select Feature';
            app.HypervolumeTab = uitab(app.TabGroup);
            app.HypervolumeTab.Title = 'Hypervolume';
            app.HypervolumeAxes = uiaxes(app.HypervolumeTab);
            title(app.HypervolumeAxes, 'Hypervolume Plot')
            xlabel(app.HypervolumeAxes, 'X')
            ylabel(app.HypervolumeAxes, 'Y')
            app.IndividualHypervolumeAxes = uiaxes(app.HypervolumeTab);
            title(app.IndividualHypervolumeAxes, 'Individual Hypervolume Plot')
            xlabel(app.IndividualHypervolumeAxes, 'X')
            ylabel(app.IndividualHypervolumeAxes, 'Y')
            app.NFEforHVjumpTextAreaLabel = uilabel(app.HypervolumeTab);
            app.NFEforHVjumpTextAreaLabel.Text = 'NFE for HV jump';
            app.NFEforHVjumpTextArea = uitextarea(app.HypervolumeTab);
            app.NFEBoxAxes = uiaxes(app.HypervolumeTab);
            title(app.NFEBoxAxes, 'NFE Box Plot')
            xlabel(app.NFEBoxAxes, 'X')
            ylabel(app.NFEBoxAxes, 'Y')
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.UIFigure);
            app.AlgorithmDropDown.Items = {'Select Algorithm', 'Epsilon MOEA', 'AOS - Constraint Independent', 'AOS - Feasibility Dependent', 'AOS - Constraint Dependent', 'Disjunctive Normal Form', 'Adaptive Constraint Handling'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'Select Algorithm';
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'Select Model', 'Fibre Stiffness', 'Truss Stiffness'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Select Model';
            app.VISUALIZEButton = uibutton(app.UIFigure, 'push');
            app.VISUALIZEButton.ButtonPushedFcn = createCallbackFcn(app, @VISUALIZEButtonPushed, true);
            app.VISUALIZEButton.Text = 'VISUALIZE';
            app.RunNumberEditFieldLabel = uilabel(app.UIFigure);
            app.RunNumberEditFieldLabel.Text = 'Run Number';
            app.RunNumberEditField = uieditfield(app.UIFigure, 'text');
            app.RunNumberEditField.ValueChangedFcn = createCallbackFcn(app, @RunNumberEditFieldValueChanged, true);
            app.InitializationDropDownLabel = uilabel(app.UIFigure);
            app.InitializationDropDownLabel.Text = 'Initialization';
            app.InitializationDropDown = uidropdown(app.UIFigure);
            app.InitializationDropDown.Items = {'Select Initialization', 'Biased Initialization', 'Random Initialization'};
            app.InitializationDropDown.ValueChangedFcn = createCallbackFcn(app, @InitializationDropDownValueChanged, true);
            app.InitializationDropDown.Value = 'Select Initialization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrussGAVizapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end